classdef NaeProjectApp306 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        costovertimeTab         matlab.ui.container.Tab
        YearInstaledSlider      matlab.ui.control.Slider
        Image                   matlab.ui.control.Image
        Hyperlink               matlab.ui.control.Hyperlink
        TitleTextArea           matlab.ui.control.TextArea
        StartButton             matlab.ui.control.Button
        CompaniesDropDown       matlab.ui.control.DropDown
        CompanyinfoTextArea     matlab.ui.control.TextArea
        CompaniesDropDownLabel  matlab.ui.control.Label
        RegionDropDown          matlab.ui.control.DropDown
        RegionDropDownLabel     matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        clicked = 0; % Click on start and finish
        CompanyName = []; 
        CompanyChosen = []; % Select a companie
        year = readmatrix('state.csv')
        state = readcell('state.csv')
        total = readmatrix('total.csv')
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CompaniesDropDown
        function CompaniesDropDownValueChanged(app, event)
            load infosGrossPrices.mat
            load infosCompanyNames.mat
            load plotfits.mat
            load years.mat
            opts = spreadsheetImportOptions("NumVariables", 5);
            opts.Sheet = "Sheet1";
            opts.DataRange = "A1:E10";
            opts.VariableNames = ["COMPANIES", "SUNPOWER", "LG", "REC", "Panasonic"];
            opts.VariableTypes = ["string", "string", "string", "string", "string"];
            opts = setvaropts(opts, ["COMPANIES", "SUNPOWER", "LG", "REC", "Panasonic"], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, ["COMPANIES", "SUNPOWER", "LG", "REC", "Panasonic"], "EmptyFieldRule", "auto");
            Infos = readtable("Infos.xlsx", opts, "UseExcel", false);
            clear opts
            Infos(2,:)=[];
            Infos=table2array(Infos);

            switch app.CompaniesDropDown.Value
                case 'Sunpower'
                    app.Hyperlink.Text = 'Sunpower website';
                    app.Hyperlink.URL = 'https://us.sunpower.com/';
                    app.Image.ImageSource = 'SunPower-logo.JPG';
                    app.CompanyinfoTextArea.Value=Infos(2)+": "+Infos(11)...
                        + newline + Infos(3)+": "+Infos(12) ...
                        + newline + Infos(4)+": "+Infos(13) ...
                        + newline + Infos(5)+": "+Infos(14) ...
                        + newline + Infos(6)+": "+Infos(15) ...
                        + newline + Infos(7)+": "+Infos(16) ...
                        + newline + Infos(8)+": "+Infos(17) ...
                        + newline + Infos(9)+": "+Infos(18);
                case 'LG'
                    app.Hyperlink.Text = 'LG website';
                    app.Image.ImageSource = 'LG logo.png';
                    app.Hyperlink.URL = 'https://www.lg.com/us/solar';
                    app.CompanyinfoTextArea.Value=Infos(2)+": "+Infos(20)...
                        + newline + Infos(3)+": "+Infos(21) ...
                        + newline + Infos(4)+": "+Infos(22) ...
                        + newline + Infos(5)+": "+Infos(23) ...
                        + newline + Infos(6)+": "+Infos(24) ...
                        + newline + Infos(7)+": "+Infos(25) ...
                        + newline + Infos(8)+": "+Infos(26) ...
                        + newline + Infos(9)+": "+Infos(27);
                case 'REC'
                    app.Hyperlink.Text = 'REC website';
                    app.Image.ImageSource = 'rec logo.png';
                    app.Hyperlink.URL = 'https://usa.recgroup.com/';
                    app.CompanyinfoTextArea.Value=Infos(2)+": "+Infos(29)...
                        + newline + Infos(3)+": "+Infos(30) ...
                        + newline + Infos(4)+": "+Infos(31) ...
                        + newline + Infos(5)+": "+Infos(32) ...
                        + newline + Infos(6)+": "+Infos(33) ...
                        + newline + Infos(7)+": "+Infos(34) ...
                        + newline + Infos(8)+": "+Infos(35) ...
                        + newline + Infos(9)+": "+Infos(36);
                case 'Panasonic'
                    app.Hyperlink.Text = 'Panasonic website';
                    app.Hyperlink.URL = 'https://na.panasonic.com/us/energy-solutions/solar/';
                    app.Image.ImageSource = 'Panasonic logo.jpg';
                    app.CompanyinfoTextArea.Value=Infos(2)+": "+Infos(38)...
                        + newline + Infos(3)+": "+Infos(39) ...
                        + newline + Infos(4)+": "+Infos(40) ...
                        + newline + Infos(5)+": "+Infos(41) ...
                        + newline + Infos(6)+": "+Infos(42) ...
                        + newline + Infos(7)+": "+Infos(43) ...
                        + newline + Infos(8)+": "+Infos(44) ...
                        + newline + Infos(9)+": "+Infos(45);
            end
            %Herbert
            Company = app.CompaniesDropDown.Value;
            switch Company
                case 'Choose a company'
                case Company

                    Company = app.CompaniesDropDown.Value;
                    namePos = find(infosCompanyNames == Company)
                    selCompany = infosGrossPrices(namePos)
                    Gross = @(x) selCompany
                    grid(app.UIAxes2,"on")
                    xlabel (app.UIAxes2,'Time [years]');
                    ylabel(app.UIAxes2,'Cost [dollars]');
                    title(app.UIAxes2,'Payback Time');
                    fplot(app.UIAxes2,Gross,'green')
                    if app.YearInstaledSlider.Value > app.year(end)
                        sliderValue = app.YearInstaledSlider.Value;
                        cla(app.UIAxes2,'reset')
                        for k = 1:30
                            nonsolar(k) = feval(fplotFit,sliderValue)*k
                        end

                        %
                        Company = app.CompaniesDropDown.Value;
                        namePos = find(infosCompanyNames == Company)
                        selCompany = infosGrossPrices(namePos)
                        Gross = @(x) selCompany


                        fplot(app.UIAxes2,Gross,'green')

                        hold(app.UIAxes2,"on")
                        % cost
                        cost(1) = (plotFit(1)*(sliderValue(1))+plotFit(2))
                        for x = 1:29
                            cost(x+1) = (plotFit(1)*(sliderValue(1)+x)+plotFit(2)) + sum(cost)
                        end
                        plot(app.UIAxes2,[sliderValue:sliderValue+29],cost,'black')
                        xlim(app.UIAxes2,[app.year(1)-30,app.year(1)+40])
                        ylim(app.UIAxes2,[0,40000])
                        grid(app.UIAxes2,"on")
                        xlabel (app.UIAxes2,'Time [years]');
                        ylabel(app.UIAxes2,'Cost [dollars]');
                        title(app.UIAxes2,'Payback Time');
                    end
            end


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.clicked == 0
                app.clicked = 1;
                app.CompaniesDropDown.Visible = 1;
                app.CompaniesDropDown.Enable = 1;
                app.CompaniesDropDownLabel.Visible = 1;
                app.CompaniesDropDownLabel.Enable = 1;
                app.RegionDropDown.Visible = 1;
                app.RegionDropDown.Enable = 1;
                app.RegionDropDownLabel.Visible = 1;
                app.RegionDropDownLabel.Enable = 1;
                app.CompanyinfoTextArea.Visible = 1; 
                app.CompanyinfoTextArea.Enable = 1; 
                app.Hyperlink.Visible = 1; 
                app.Hyperlink.Enable = 1; 
                app.UIAxes2.Visible = 1;
                app.Image.Visible = 1; 
                app.Image.Enable = 1;
                
                              
                app.StartButton.Text = 'Quit app';
            elseif app.clicked == 1
                pause(1)
                closereq

            end
        end

        % Value changed function: YearInstaledSlider
        function YearInstaledSliderValueChanged(app, event)
          sliderValue = app.YearInstaledSlider.Value;
            load infosGrossPrices.mat
            load infosCompanyNames.mat
            load plotfits.mat
            load years.mat

            cla(app.UIAxes2,'reset')
            for k = 1:30
                nonsolar(k) = feval(fplotFit,sliderValue)*k
            end
            
%       
            Company = app.CompaniesDropDown.Value;
            namePos = find(infosCompanyNames == Company)
            selCompany = infosGrossPrices(namePos)
            Gross = @(x) selCompany
            
            
            fplot(app.UIAxes2,Gross,'green')
            
            hold(app.UIAxes2,"on")
            % cost
            cost(1) = (plotFit(1)*(sliderValue(1))+plotFit(2))
            for x = 1:29
                cost(x+1) = (plotFit(1)*(sliderValue(1)+x)+plotFit(2)) + sum(cost)
            end
            plot(app.UIAxes2,[sliderValue:sliderValue+29],cost,'black')
            xlim(app.UIAxes2,[app.year(1)-30,app.year(1)+40])
            ylim(app.UIAxes2,[0,40000])
            grid(app.UIAxes2,"on")
            xlabel (app.UIAxes2,'Time [years]');
            ylabel(app.UIAxes2,'Cost [dollars]');
            title(app.UIAxes2,'Payback Time');
            
        end

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            value = app.RegionDropDown.Value;
            find(app.year==2020)

            switch value
                case 'SELECT'
                    
                case value
                    app.year = readmatrix('year.csv')
                    app.total = readmatrix('total.csv')
                    load state.mat
                    load sectorCategoryTotal.mat
                    % finding years for x axis

                    yearDiff = app.year(1)-app.year(end)
                    for k = 1:yearDiff
                        yearPosition = find(app.year == (app.year(1)-(k-1))) %replace 2020 for app.year(1)
                        years(k)= app.year(yearPosition(1))
                    end
                    save ('years.mat',"years")

                    %find location of the state
                    sectorCate = find(sectorCategoryTotal=='Total Electric Industry')
                    regionLocations = find(state == value)
                    regionLocations = 52-regionLocations
                    stateLoc = []
                    for k=2:length(sectorCate)
                        if sectorCate(k)~= sectorCate(k-1)+1
                            stateLoc = [stateLoc,sectorCate(k-1)-regionLocations]
                        end
                    end
                    %find price at each state
                    for k = 1:length(stateLoc)
                        stateTotal(k) = app.total(stateLoc(k))
                    end
                    stateTotal = stateTotal*10*12;
                    app.YearInstaledSlider.Visible = 'on'
                    app.YearInstaledSlider.Enable = 'on'

            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.costovertimeTab = uitab(app.TabGroup);
            app.costovertimeTab.Title = 'cost over time';
            app.UIAxes2 = uiaxes(app.costovertimeTab);
            title(app.UIAxes2, 'Payback time ')
            xlabel(app.UIAxes2, 'Time [years]')
            ylabel(app.UIAxes2, 'Cost [dollars]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.RegionDropDownLabel = uilabel(app.costovertimeTab);
            app.RegionDropDownLabel.Enable = 'off';
            app.RegionDropDownLabel.Visible = 'off';
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.costovertimeTab);
            app.RegionDropDown.Items = {'AK', 'AL', 'AR', 'AZ', 'CA', 'CO', 'CT', 'DC', 'DE', 'FL', 'GA', 'HI', 'IA', 'ID', 'IL', 'IN', 'KS', 'KY', 'LA', 'MA', 'MD', 'ME', 'MI', 'MN', 'MO', 'MS', 'MT', 'NC', 'ND', 'NE', 'NH', 'NJ', 'NM', 'NV', 'NY', 'OH', 'OK', 'OR', 'PA', 'RI', 'SC', 'SD', 'TN', 'TX', 'UT', 'VA', 'VT', 'WA', 'WI', 'WV', 'WY', 'US', ''};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Enable = 'off';
            app.RegionDropDown.Visible = 'off';
            app.RegionDropDown.Value = 'AK';
            app.CompaniesDropDownLabel = uilabel(app.costovertimeTab);
            app.CompaniesDropDownLabel.Enable = 'off';
            app.CompaniesDropDownLabel.Visible = 'off';
            app.CompaniesDropDownLabel.Text = 'Companies ';
            app.CompanyinfoTextArea = uitextarea(app.costovertimeTab);
            app.CompanyinfoTextArea.Visible = 'off';
            app.CompaniesDropDown = uidropdown(app.costovertimeTab);
            app.CompaniesDropDown.Items = {'Choose a company', 'Sunpower', 'LG', 'REC', 'Panasonic'};
            app.CompaniesDropDown.ValueChangedFcn = createCallbackFcn(app, @CompaniesDropDownValueChanged, true);
            app.CompaniesDropDown.Enable = 'off';
            app.CompaniesDropDown.Visible = 'off';
            app.CompaniesDropDown.Value = 'Choose a company';
            app.StartButton = uibutton(app.costovertimeTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'matlab icon.jpeg';
            app.StartButton.Text = 'Start';
            app.TitleTextArea = uitextarea(app.costovertimeTab);
            app.TitleTextArea.Value = {'Efficiency of Utilizing Solar Energy in Households'};
            app.Hyperlink = uihyperlink(app.costovertimeTab);
            app.Hyperlink.Visible = 'off';
            app.Image = uiimage(app.costovertimeTab);
            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'roof.jpg';
            app.YearInstaledSlider = uislider(app.costovertimeTab);
            app.YearInstaledSlider.Limits = [2000 2050];
            app.YearInstaledSlider.ValueChangedFcn = createCallbackFcn(app, @YearInstaledSliderValueChanged, true);
            app.YearInstaledSlider.Enable = 'off';
            app.YearInstaledSlider.Visible = 'off';
            app.YearInstaledSlider.Value = 2000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NaeProjectApp306

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end